classdef gps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StopButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    gpsTimer
    latData
    lonData
    currentIndex = 1
    routeLine
    currentPoint % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          

  % Example GPS route around UC Davis
    app.latData = [38.5382 38.5385 38.5388 38.5391 38.5394 38.5397];
    app.lonData = [-121.7617 -121.7613 -121.7609 -121.7605 -121.7601 -121.7597];

    % Use normal UIAxes to simulate GPS route
    cla(app.UIAxes)
    hold(app.UIAxes, "on")
    grid(app.UIAxes, "on")

    xlabel(app.UIAxes, "Longitude")
    ylabel(app.UIAxes, "Latitude")
    title(app.UIAxes, "Simulated GPS Route")

    xlim(app.UIAxes, [min(app.lonData)-0.001, max(app.lonData)+0.001])
    ylim(app.UIAxes, [min(app.latData)-0.001, max(app.latData)+0.001])

    % Empty route history line
    app.routeLine = plot(app.UIAxes, NaN, NaN, "-o", "LineWidth", 2);

    % Current moving GPS point
    app.currentPoint = plot(app.UIAxes, NaN, NaN, "ro", ...
        "MarkerSize", 10, "MarkerFaceColor", "r");

    % Timer for live movement
    app.gpsTimer = timer( ...
        "ExecutionMode", "fixedRate", ...
        "Period", 1, ...
        "TimerFcn", @(~,~) updateGPS(app));

    end


    function updateGPS(app)

    if app.currentIndex > length(app.latData)
        stop(app.gpsTimer)
        return
    end

    latHistory = app.latData(1:app.currentIndex);
    lonHistory = app.lonData(1:app.currentIndex);

    % Update route history
    app.routeLine.XData = lonHistory;
    app.routeLine.YData = latHistory;

    % Update current moving point
    app.currentPoint.XData = app.lonData(app.currentIndex);
    app.currentPoint.YData = app.latData(app.currentIndex);

    app.currentIndex = app.currentIndex + 1;

    end


    function UIFigureCloseRequest(app, event)

    if ~isempty(app.gpsTimer) && isvalid(app.gpsTimer)
        stop(app.gpsTimer)
        delete(app.gpsTimer)
    end

    delete(app)


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           if strcmp(app.gpsTimer.Running, "on")
           stop(app.gpsTimer)
           end

         app.currentIndex = 1;

         app.routeLine.XData = NaN;
         app.routeLine.YData = NaN;

         app.currentPoint.XData = NaN;
         app.currentPoint.YData = NaN;


        start(app.gpsTimer)

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
          
        stop(app.gpsTimer)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end